classdef IndianMonsoonLPSDashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LPSTracksPanel                 matlab.ui.container.Panel
        YearDropDown                   matlab.ui.control.DropDown
        MonthDropDown                  matlab.ui.control.DropDown
        SpanDropDown                   matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        Panel                          matlab.ui.container.Panel
        IndianMonsoonLPSExplorerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        LPS_TRACK_DATA 
        SpanHours
        CoastLat
        CoastLon

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set app window background to white
            app.UIFigure.Color = [1 1 1];
            
            % Maximize app window
            app.UIFigure.WindowState = 'maximized';
            
                       
            dataFile = 'D:\INTERACTIVE_DASHBOARD\DATA\LPS_Track_Data.mat';

            S = load(dataFile, 'LPS_TRACK_DATA');
            
            app.LPS_TRACK_DATA = S.LPS_TRACK_DATA;


             %===================== ADD SPAN DATA =====================

            spanFile = 'D:\INTERACTIVE_DASHBOARD\DATA\LPS_Span_Data.mat';
            S = load(spanFile, 'SpanHours');
            
            app.SpanHours = S.SpanHours;


            %===================== ADD COASTLINES DATA =====================

            C = load("D:\INTERACTIVE_DASHBOARD\DATA\Coastlines.mat", 'coastlat', 'coastlon');
            
            app.CoastLat = C.coastlat;
            app.CoastLon = C.coastlon;


  %===================== ADD TRACKS ====================

            cla(app.UIAxes);
        hold(app.UIAxes, 'on');


            % Draw coastlines
            plot(app.UIAxes, app.CoastLon, app.CoastLat, 'k-', 'LineWidth', 1.0);
            hold(app.UIAxes, 'on');

        for i = 1:numel(app.LPS_TRACK_DATA)
            track = app.LPS_TRACK_DATA{i};
        
            if isempty(track)
                continue
            end
        
            plot(app.UIAxes, track(:,1), track(:,2), '-', 'Color', [0.25 0.45 0.70], 'LineWidth', 0.8);
        end


        % Mark genesis locations with red circles
        genesisLon = nan(numel(app.LPS_TRACK_DATA), 1);
        genesisLat = nan(numel(app.LPS_TRACK_DATA), 1);
        
        for i = 1:numel(app.LPS_TRACK_DATA)
            track = app.LPS_TRACK_DATA{i};
        
            genesisLon(i) = track(1,1);
            genesisLat(i) = track(1,2);
        end
        
        scatter(app.UIAxes, genesisLon, genesisLat, 20, 'r', 'filled','o','MarkerEdgeColor','k');

        % Collect and plot demise points for all systems
        demiseLon = nan(numel(app.LPS_TRACK_DATA),1);
        demiseLat = nan(numel(app.LPS_TRACK_DATA),1);
        
        for i = 1:numel(app.LPS_TRACK_DATA)
            track = app.LPS_TRACK_DATA{i};
        
            if isempty(track)
                continue
            end
        
            demiseLon(i) = track(end,1);
            demiseLat(i) = track(end,2);
        end
        
        scatter(app.UIAxes, demiseLon, demiseLat, 20, 'k', 'filled','o','MarkerEdgeColor','k');

        % Dummy handles for a correctly styled legend
        hTrack = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 1.2);
        hGenesis = scatter(app.UIAxes, NaN, NaN, 35, 'r', 'filled');
        hDemise = scatter(app.UIAxes, NaN, NaN, 35, 'k', 'filled');
        
        legend(app.UIAxes,[hTrack, hGenesis, hDemise], {'Tracks','Genesis','Demise'}, ...
        'Location','northeast','FontSize',14,'FontWeight','bold');
        
        xlabel(app.UIAxes, 'Longitude (^oE)');
        ylabel(app.UIAxes, 'Latitude (^oN)');

        % Latitude and longitude tick labels
        app.UIAxes.FontSize = 20;
        app.UIAxes.FontWeight = 'bold';
        app.UIAxes.LineWidth = 1.2;
        
        xlim(app.UIAxes, [65 100]);
        ylim(app.UIAxes, [5 35]);

        grid(app.UIAxes,'on');
        app.UIAxes.GridLineStyle = '--';
        app.UIAxes.GridAlpha = 0.4;
        app.UIAxes.LineWidth = 0.4;

        box(app.UIAxes, 'on');
        hold(app.UIAxes, 'off');


  %===================== ADD MAP =====================

        app.UIAxes.Color = 'white';
        app.UIAxes.FontSize = 17;
        app.UIAxes.LineWidth = 1;
        app.UIAxes.DataAspectRatio = [1 cosd(20) 1];
        app.UIAxes.DataAspectRatioMode = 'manual';

        end

        % Callback function: not associated with a component
        function EpochDropDownValueChanged(app, event)
            value = app.EpochDropDown.Value;

            cla(app.UIAxes);
        
% Redraw coastlines after clearing the axes
plot(app.UIAxes, app.CoastLon, app.CoastLat, ...
    'k-', 'LineWidth', 1.0);

    hold(app.UIAxes, 'on');

    % Selected filters
    epochChoice = app.EpochDropDown.Value;
    spanChoice = app.SpanDropDown.Value;
    selectedYear = app.YearEditField.Value;
    monthChoice = app.MonthDropDown.Value;

    % Convert month selection to number
    switch monthChoice
        case 'June'
            selectedMonth = 6;
        case 'July'
            selectedMonth = 7;
        case 'August'
            selectedMonth = 8;
        case 'September'
            selectedMonth = 9;
        otherwise
            selectedMonth = 0;
    end

    % Draw coastlines
    plot(app.UIAxes, app.CoastLon, app.CoastLat, ...
        'k-', 'LineWidth', 1.0);

    % Store genesis locations of filtered systems
    genesisLon = [];
    genesisLat = [];

    % Loop through all LPS tracks
    for i = 1:numel(app.LPS_TRACK_DATA)

        track = app.LPS_TRACK_DATA{i};

        if isempty(track)
            continue
        end

        systemYear = track(1,5);
        duration = app.SpanHours(i);

        % Epoch filter
        if strcmp(epochChoice, 'EP1 (1979–2000)') && ...
                (systemYear < 1979 || systemYear > 2000)
            continue
        elseif strcmp(epochChoice, 'EP2 (2001–2022)') && ...
                (systemYear < 2001 || systemYear > 2022)
            continue
        end

        % Span filter
        if strcmp(spanChoice, 'Short Span (<=137 h)') && duration > 137
            continue
        elseif strcmp(spanChoice, 'Long Span (>137 h)') && duration <= 137
            continue
        end

        % Year filter
        if systemYear ~= selectedYear
            continue
        end

        % Month filter
        if selectedMonth ~= 0 && ~any(track(:,6) == selectedMonth)
            continue
        end

        % Plot filtered track
        plot(app.UIAxes, track(:,1), track(:,2), ...
            '-', 'Color', [0.25 0.45 0.70], ...
            'LineWidth', 0.8);

        % Save genesis location
        genesisLon(end+1) = track(1,1);
        genesisLat(end+1) = track(1,2);

    end

    % Plot red genesis points
    scatter(app.UIAxes, genesisLon, genesisLat, ...
        35, 'r', 'filled');

    % Map formatting
    xlim(app.UIAxes, [65 100]);
    ylim(app.UIAxes, [5 35]);
    xlabel(app.UIAxes, 'Longitude');
    ylabel(app.UIAxes, 'Latitude');
    grid(app.UIAxes, 'on');
    box(app.UIAxes, 'on');

    app.UIAxes.Color = 'white';
    app.UIAxes.FontSize = 11;
    app.UIAxes.LineWidth = 1;
    app.UIAxes.DataAspectRatio = [1 cosd(20) 1];
    app.UIAxes.DataAspectRatioMode = 'manual';

    hold(app.UIAxes, 'off');

        end

        % Value changed function: SpanDropDown
        function SpanDropDownValueChanged(app, event)
            value = app.SpanDropDown.Value;

            cla(app.UIAxes);


    % Redraw coastlines after clearing the axes
    plot(app.UIAxes, app.CoastLon, app.CoastLat, 'k-', 'LineWidth', 1.0);

    hold(app.UIAxes, 'on');

    % epochChoice = app.EpochDropDown.Value;
    spanChoice = app.SpanDropDown.Value;
    yearChoice = app.YearDropDown.Value;
    monthChoice = app.MonthDropDown.Value;

    switch monthChoice
        case 'June'
            selectedMonth = 6;
        case 'July'
            selectedMonth = 7;
        case 'August'
            selectedMonth = 8;
        case 'September'
            selectedMonth = 9;
        otherwise
            selectedMonth = 0;
    end

    % Draw coastlines
    plot(app.UIAxes, app.CoastLon, app.CoastLat, ...
        'k-', 'LineWidth', 1.0);

    genesisLon = [];
    genesisLat = [];

    demiseLon = [];
    demiseLat = [];

    for i = 1:numel(app.LPS_TRACK_DATA)

        track = app.LPS_TRACK_DATA{i};

        if isempty(track)
            continue
        end

        systemYear = track(1,5);
        duration = app.SpanHours(i);

        % Epoch filter
        % if strcmp(epochChoice, 'EP1 (1979–2000)') && ...
        %         (systemYear < 1979 || systemYear > 2000)
        %     continue
        % elseif strcmp(epochChoice, 'EP2 (2001–2022)') && ...
        %         (systemYear < 2001 || systemYear > 2022)
        %     continue
        % end

        % Span filter
        if strcmp(spanChoice, 'Short Span (<=137 h)') && duration > 137
            continue
        elseif strcmp(spanChoice, 'Long Span (>137 h)') && duration <= 137
            continue
        end

        % Apply year filter only when a specific year is selected
        if ~strcmp(yearChoice, 'All Years') && ...
                systemYear ~= str2double(yearChoice)
            continue
        end

        % Month filter
        if selectedMonth ~= 0 && ~any(track(:,6) == selectedMonth)
            continue
        end

        plot(app.UIAxes, track(:,1), track(:,2), '-', 'Color', [0.25 0.45 0.70], 'LineWidth', 0.8);

        genesisLon(end+1) = track(1,1);
        genesisLat(end+1) = track(1,2);

        % Collect demise coordinates
        demiseLon(end+1) = track(end,1);
        demiseLat(end+1) = track(end,2);

    end

    scatter(app.UIAxes, genesisLon, genesisLat, 20, 'r', 'filled','o');
    hold(app.UIAxes, 'on');

    % Plot demise points in black
    scatter(app.UIAxes, demiseLon, demiseLat, 20, 'k', 'filled','o');

     % Dummy handles for a correctly styled legend
    hTrack = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 1.2);
    hGenesis = scatter(app.UIAxes, NaN, NaN, 35, 'r', 'filled');
    hDemise = scatter(app.UIAxes, NaN, NaN, 35, 'k', 'filled');
    
    legend(app.UIAxes,[hTrack, hGenesis, hDemise], {'Tracks','Genesis','Demise'}, ...
    'Location','northeast','FontSize',14,'FontWeight','bold');

    xlim(app.UIAxes, [65 100]);
    ylim(app.UIAxes, [5 35]);
    xlabel(app.UIAxes, 'Longitude (^oE)');
    ylabel(app.UIAxes, 'Latitude (^oN)');

    % Latitude and longitude tick labels
    app.UIAxes.FontSize = 20;
    app.UIAxes.FontWeight = 'bold';
    app.UIAxes.LineWidth = 1.2;
    
    grid(app.UIAxes, 'on');
    app.UIAxes.GridLineStyle = '--';
    app.UIAxes.GridAlpha = 0.4;
    app.UIAxes.LineWidth = 0.4;

    box(app.UIAxes, 'on');

    app.UIAxes.Color = 'white';
    app.UIAxes.FontSize = 17;
    app.UIAxes.LineWidth = 1;
    app.UIAxes.DataAspectRatio = [1 cosd(20) 1];
    app.UIAxes.DataAspectRatioMode = 'manual';

    hold(app.UIAxes, 'off');


        end

        % Callback function: not associated with a component
        function YearEditFieldValueChanged(app, event)
            value = app.YearEditField.Value;
            cla(app.UIAxes);

    hold(app.UIAxes, 'on');

    % epochChoice = app.EpochDropDown.Value;
    spanChoice = app.SpanDropDown.Value;
    selectedYear = app.YearEditField.Value;
    monthChoice = app.MonthDropDown.Value;

    switch monthChoice
        case 'June'
            selectedMonth = 6;
        case 'July'
            selectedMonth = 7;
        case 'August'
            selectedMonth = 8;
        case 'September'
            selectedMonth = 9;
        otherwise
            selectedMonth = 0;
    end

    % Draw coastlines
    plot(app.UIAxes, app.CoastLon, app.CoastLat, ...
        'k-', 'LineWidth', 1.0);

    genesisLon = [];
    genesisLat = [];

    for i = 1:numel(app.LPS_TRACK_DATA)

        track = app.LPS_TRACK_DATA{i};

        if isempty(track)
            continue
        end

        systemYear = track(1,5);
        duration = app.SpanHours(i);

        % Epoch filter
        % if strcmp(epochChoice, 'EP1 (1979–2000)') && ...
        %         (systemYear < 1979 || systemYear > 2000)
        %     continue
        % elseif strcmp(epochChoice, 'EP2 (2001–2022)') && ...
        %         (systemYear < 2001 || systemYear > 2022)
        %     continue
        % end

        % Span filter
        if strcmp(spanChoice, 'Short Span (<=137 h)') && duration > 137
            continue
        elseif strcmp(spanChoice, 'Long Span (>137 h)') && duration <= 137
            continue
        end

        % Year filter
        if systemYear ~= selectedYear
            continue
        end

        % Month filter
        if selectedMonth ~= 0 && ~any(track(:,6) == selectedMonth)
            continue
        end

        plot(app.UIAxes, track(:,1), track(:,2), ...
            '-', 'Color', [0.25 0.45 0.70], ...
            'LineWidth', 0.8);

        genesisLon(end+1) = track(1,1);
        genesisLat(end+1) = track(1,2);

    end

    scatter(app.UIAxes, genesisLon, genesisLat, ...
        35, 'r', 'filled');

    xlim(app.UIAxes, [65 100]);
    ylim(app.UIAxes, [5 35]);
    xlabel(app.UIAxes, 'Longitude');
    ylabel(app.UIAxes, 'Latitude');
    grid(app.UIAxes, 'on');
    box(app.UIAxes, 'on');

    app.UIAxes.Color = 'white';
    app.UIAxes.FontSize = 11;
    app.UIAxes.LineWidth = 1;
    app.UIAxes.DataAspectRatio = [1 cosd(20) 1];
    app.UIAxes.DataAspectRatioMode = 'manual';

    hold(app.UIAxes, 'off');


        end

        % Value changed function: MonthDropDown
        function MonthDropDownValueChanged(app, event)
            value = app.MonthDropDown.Value;
            cla(app.UIAxes);

   hold(app.UIAxes, 'on');

    % epochChoice = app.EpochDropDown.Value;
    spanChoice = app.SpanDropDown.Value;
    yearChoice = app.YearDropDown.Value;
    monthChoice = app.MonthDropDown.Value;

    switch monthChoice
        case 'June'
            selectedMonth = 6;
        case 'July'
            selectedMonth = 7;
        case 'August'
            selectedMonth = 8;
        case 'September'
            selectedMonth = 9;
        otherwise
            selectedMonth = 0;
    end

    % Draw coastlines
    plot(app.UIAxes, app.CoastLon, app.CoastLat, ...
        'k-', 'LineWidth', 1.0);

    genesisLon = [];
    genesisLat = [];

    demiseLon = [];
    demiseLat = [];

    for i = 1:numel(app.LPS_TRACK_DATA)

        track = app.LPS_TRACK_DATA{i};

        if isempty(track)
            continue
        end

        systemYear = track(1,5);
        duration = app.SpanHours(i);

        % % Epoch filter
        % if strcmp(epochChoice, 'EP1 (1979–2000)') && ...
        %         (systemYear < 1979 || systemYear > 2000)
        %     continue
        % elseif strcmp(epochChoice, 'EP2 (2001–2022)') && ...
        %         (systemYear < 2001 || systemYear > 2022)
        %     continue
        % end

        % Span filter
        if strcmp(spanChoice, 'Short Span (<=137 h)') && duration > 137
            continue
        elseif strcmp(spanChoice, 'Long Span (>137 h)') && duration <= 137
            continue
        end

        % Apply year filter only when a specific year is selected
        if ~strcmp(yearChoice, 'All Years') && ...
                systemYear ~= str2double(yearChoice)
            continue
        end

        % Month filter
        if selectedMonth ~= 0 && ~any(track(:,6) == selectedMonth)
            continue
        end

        plot(app.UIAxes, track(:,1), track(:,2),'-', 'Color', [0.25 0.45 0.70], 'LineWidth', 0.8);

        genesisLon(end+1) = track(1,1);
        genesisLat(end+1) = track(1,2);

        % Collect demise coordinates
        demiseLon(end+1) = track(end,1);
        demiseLat(end+1) = track(end,2);

    end

    scatter(app.UIAxes, genesisLon, genesisLat, 20, 'r', 'filled');
    hold(app.UIAxes, 'on');

    % Plot demise points in black
    scatter(app.UIAxes, demiseLon, demiseLat, 20, 'k', 'filled');

     % Dummy handles for a correctly styled legend
    hTrack = plot(app.UIAxes, NaN, NaN, 'b-', 'LineWidth', 1.2);
    hGenesis = scatter(app.UIAxes, NaN, NaN, 35, 'r', 'filled');
    hDemise = scatter(app.UIAxes, NaN, NaN, 35, 'k', 'filled');
    
    legend(app.UIAxes,[hTrack, hGenesis, hDemise], {'Tracks','Genesis','Demise'}, ...
    'Location','northeast','FontSize',14,'FontWeight','bold');

    xlim(app.UIAxes, [65 100]);
    ylim(app.UIAxes, [5 35]);
    xlabel(app.UIAxes, 'Longitude (^oE)');
    ylabel(app.UIAxes, 'Latitude (^oN)');

    % Latitude and longitude tick labels
    app.UIAxes.FontSize = 20;
    app.UIAxes.FontWeight = 'bold';
    app.UIAxes.LineWidth = 1.2;
    
    grid(app.UIAxes, 'on');
    app.UIAxes.GridLineStyle = '--';
    app.UIAxes.GridAlpha = 0.4;
    app.UIAxes.LineWidth = 0.4;

    box(app.UIAxes, 'on');

    app.UIAxes.Color = 'white';
    app.UIAxes.FontSize = 17;
    app.UIAxes.LineWidth = 1;
    app.UIAxes.DataAspectRatio = [1 cosd(20) 1];
    app.UIAxes.DataAspectRatioMode = 'manual';

    hold(app.UIAxes, 'off');

        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            value = app.YearDropDown.Value;
            
             SpanDropDownValueChanged(app, []);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.IndianMonsoonLPSExplorerLabel = uilabel(app.Panel);
            app.IndianMonsoonLPSExplorerLabel.Text = 'Indian Monsoon LPS Explorer';
            app.LPSTracksPanel = uipanel(app.UIFigure);
            app.LPSTracksPanel.Title = 'LPS Tracks';
            app.UIAxes = uiaxes(app.LPSTracksPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SpanDropDown = uidropdown(app.LPSTracksPanel);
            app.SpanDropDown.Items = {'All Systems', 'Short Span (<=137 h)', 'Long Span (>137 h)'};
            app.SpanDropDown.ValueChangedFcn = createCallbackFcn(app, @SpanDropDownValueChanged, true);
            app.SpanDropDown.Value = 'All Systems';
            app.MonthDropDown = uidropdown(app.LPSTracksPanel);
            app.MonthDropDown.Items = {'All Months', 'June', 'July', 'August', 'September'};
            app.MonthDropDown.ValueChangedFcn = createCallbackFcn(app, @MonthDropDownValueChanged, true);
            app.MonthDropDown.Value = 'All Months';
            app.YearDropDown = uidropdown(app.LPSTracksPanel);
            app.YearDropDown.Items = {'All Years', '1979', '1980', '1981', '1982', '1983', '1984', '1985', '1986', '1987', '1988', '1989', '1990', '1991', '1992', '1993', '1994', '1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018', '2019', '2020', '2021', '2022'};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = 'All Years';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IndianMonsoonLPSDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end